classdef finalproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HospitalLocatorLabel            matlab.ui.control.Label
        SelectYourStateDropDownLabel    matlab.ui.control.Label
        SelectYourStateDropDown         matlab.ui.control.DropDown
        CityEditFieldLabel              matlab.ui.control.Label
        CityEditField                   matlab.ui.control.EditField
        CountyEditFieldLabel            matlab.ui.control.Label
        CountyEditField                 matlab.ui.control.EditField
        Priority1ListBoxLabel           matlab.ui.control.Label
        Priority1ListBox                matlab.ui.control.ListBox
        EmergencyServicesDropDownLabel  matlab.ui.control.Label
        EmergencyServicesDropDown       matlab.ui.control.DropDown
        Priority2ListBoxLabel           matlab.ui.control.Label
        Priority2ListBox                matlab.ui.control.ListBox
        Priority3ListBoxLabel           matlab.ui.control.Label
        Priority3ListBox                matlab.ui.control.ListBox
        BestHospitalOptionsLabel        matlab.ui.control.Label
        Priority4ListBoxLabel           matlab.ui.control.Label
        Priority4ListBox                matlab.ui.control.ListBox
        Priority5ListBoxLabel           matlab.ui.control.Label
        Priority5ListBox                matlab.ui.control.ListBox
        SubmitButton                    matlab.ui.control.Button
    end

    properties (Access = public)
        state
        county
        city
        PriorityOne
        PriorityTwo
        PriorityThree
        PriorityFour
        PriorityFive
        EmergencyServices
        table
        hospitals = []
    end
    methods (Access = public)
        
        function getdata(app)
            data = readtable('Hospital_General_Information.csv');
            app.table = table2struct(data);
        end
        
        function gethospitals(app)
            for i = 1:size(app.table)
                if string(app.table(i).City) == upper(string(app.city))
                    app.hospitals = [app.hospitals, app.table(i)];
                end
            end
                
            if length(app.hospitals) < 5
                app.hospitals = [];
                for i = 1:size(app.table) 
                    if string(app.table(i).CountyName) == upper(string(app.county))
                        app.hospitals = [app.hospitals, app.table(i)];
                    end
                end
            end
                
            if length(app.hospitals) < 5
                app.hospitals = [];
                    for i = 1:size(app.table)
                        if string(app.table(i).State) == upper(string(app.state))
                            app.hospitals = [app.hospitals, app.table(i)];
                        end
                    end
            end
        end
    end


    values = zeros(1,5);
    values = ["valueone" ,"valuetwo" ,"valuethree","valuefour","valuefive"];
    priorities = zeros(1,5);
    priorities = ["PriorityOne" ,"PriorityTwo" ,"PriorityThree","PriorityFour","PriorityFive"];
  
    for i = 1:length(hospitalarray)
    for j = 1:5
    % repeat this same exact thing for Priorities 2-5.
    switch priorities(j)
        case Effectiveness of Care
            switch table(i).EffectivenessOfCareNationalComparison
                case below the national average
                    value(j) = -1;
                case same as the national average
                    value(j) = 0;
                case above the national average
                    value(j) = 1;
            end
        case Efficient use of medical imaging
            switch table(i).Efficient use of medical Imagaging
                case below the national average
                    value(j) = -1;
                case same as the national average
                    value(j) = 0;
                case above the national average
                    value(j) = 1;
            end
        case Mortality Rate
            switch table(i).MortalityNationalComparison
                case below the national average
                    value(j) = -1;
                case same as the national average
                    value(j) = 0;
                case above the national average
                    value(j) = 1;
            end
        case Overall Rating
            switch table(i).HospitalOverallRating
                case 1
                    value(j) = -2;
                case 2
                    value(j) = -1;
                case 3
                    value(j) = 0;
                case 4
                    value(j) = 1;
                case 5
                    value(j) = 2;
            end
        case Patient Experience Rating
            switch table(i).PatientExperienceNationalComparison
                case below the national average
                    value(j) = -1;
                case same as the national average
                    value(j) = 0;
                case above the national average
                    value(j) = 1;
            end
        case Safety of Care
            switch table(i).SafetyOfCareNationalComparison
                case below the national average
                    value(j) = -1;
                case same as the national average
                    value(j) = 0;
                case above the national average
                    value(j) = 1;
            end
        case Timeliness of Care
            switch table(i).TimelinessOfCareNationalComparison
                case below the national average
                    value(j) = -1;
                case same as the national average
                    value(j) = 0;
                case above the national average
                    value(j) = 1;
            end
    end
end
    end
    
    score

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function Submit(app, event)
            % THIS IS OUR ENTIRE CALCULATION AND GRAPH FUNCTION :) :P :DDDDDD :):
            % rawr xD
            app.state = app.SelectYourStateDropDown.Value; 
            app.county = app.CountyEditField.Value;
            app.city = app.CityEditField.Value;
            app.PriorityOne = app.Priority1ListBox.Value;
            app.PriorityTwo = app.Priority2ListBox.Value;
            app.PriorityThree = app.Priority3ListBox.Value;
            app.PriorityFour = app.Priority4ListBox.Value;
            app.PriorityFive = app.Priority5ListBox.Value;
            app.EmergencyServices = app.EmergencyServicesDropDown.Value;
            getdata(app);
            gethospitals(app);
            delete(app);          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HospitalLocatorLabel = uilabel(app.UIFigure);
            app.HospitalLocatorLabel.Text = 'Hospital Locator';
            app.SelectYourStateDropDownLabel = uilabel(app.UIFigure);
            app.SelectYourStateDropDownLabel.Text = 'Select Your State';
            app.SelectYourStateDropDown = uidropdown(app.UIFigure);
            app.SelectYourStateDropDown.Items = {'Any', 'AL', 'AK', 'AZ', 'AR', 'CA', 'CO', 'CT', 'DE', 'FL', 'GA', 'HI', 'ID', 'IL', 'IN', 'IA', 'KS', 'KY', 'LA', 'ME', 'MD', 'MA', 'MI', 'MN', 'MS', 'MO', 'MT', 'NE', 'NV', 'NH', 'NJ', 'NM', 'NY', 'NC', 'ND', 'OH', 'OK', 'OR', 'PA', 'RI', 'SC', 'SD', 'TN', 'TX', 'UT', 'VT', 'VA', 'WA', 'WV', 'WI', 'WY'};
            app.SelectYourStateDropDown.Editable = 'on';
            app.SelectYourStateDropDown.Value = 'Any';
            app.CityEditFieldLabel = uilabel(app.UIFigure);
            app.CityEditFieldLabel.Text = 'City';
            app.CityEditField = uieditfield(app.UIFigure, 'text');
            app.CountyEditFieldLabel = uilabel(app.UIFigure);
            app.CountyEditFieldLabel.Text = 'County';
            app.CountyEditField = uieditfield(app.UIFigure, 'text');
            app.Priority1ListBoxLabel = uilabel(app.UIFigure);
            app.Priority1ListBoxLabel.Text = 'Priority 1';
            app.Priority1ListBox = uilistbox(app.UIFigure);
            app.Priority1ListBox.Items = {'Effectiveness of Care', 'Efficient Use of Medical Imaging', 'Hospital Ownership', 'Hospital Type', 'Location', 'Mortality Rate', 'Overall Rating', 'Quality of Emergency Rooms', 'Readmission', 'Patient Experience Rating', 'Safety of Care', 'Timeliness of Care'};
            app.Priority1ListBox.Value = 'Effectiveness of Care';
            app.EmergencyServicesDropDownLabel = uilabel(app.UIFigure);
            app.EmergencyServicesDropDownLabel.Text = 'Emergency Services';
            app.EmergencyServicesDropDown = uidropdown(app.UIFigure);
            app.EmergencyServicesDropDown.Items = {'N/A', 'Yes', 'No'};
            app.EmergencyServicesDropDown.Value = 'N/A';
            app.Priority2ListBoxLabel = uilabel(app.UIFigure);
            app.Priority2ListBoxLabel.Text = 'Priority 2';
            app.Priority2ListBox = uilistbox(app.UIFigure);
            app.Priority2ListBox.Items = {'Effectiveness of Care', 'Efficient Use of Medical Imaging', 'Hospital Ownership', 'Hospital Type', 'Location', 'Mortality Rate', 'Item 15', 'Overall Rating', 'Quality of Emergency Rooms', 'Readmission', 'Patient Experience Rating', 'Safety of Care', 'Timeliness of Care'};
            app.Priority2ListBox.Value = 'Effectiveness of Care';
            app.Priority3ListBoxLabel = uilabel(app.UIFigure);
            app.Priority3ListBoxLabel.Text = 'Priority 3';
            app.Priority3ListBox = uilistbox(app.UIFigure);
            app.Priority3ListBox.Items = {'Effectiveness of Care', 'Efficient Use of Medical Imaging', 'Hospital Ownership', 'Hospital Type', 'Location', 'Mortality Rate', 'Item 15', 'Overall Rating', 'Quality of Emergency Rooms', 'Readmission', 'Patient Experience Rating', 'Safety of Care', 'Timeliness of Care'};
            app.Priority3ListBox.Value = 'Effectiveness of Care';
            app.BestHospitalOptionsLabel = uilabel(app.UIFigure);
            app.BestHospitalOptionsLabel.Text = 'Best Hospital Options';
            app.Priority4ListBoxLabel = uilabel(app.UIFigure);
            app.Priority4ListBoxLabel.Text = 'Priority 4';
            app.Priority4ListBox = uilistbox(app.UIFigure);
            app.Priority4ListBox.Items = {'Effectiveness of Care', 'Efficient Use of Medical Imaging', 'Hospital Ownership', 'Hospital Type', 'Location', 'Mortality Rate', 'Item 15', 'Overall Rating', 'Quality of Emergency Rooms', 'Readmission', 'Patient Experience Rating', 'Safety of Care', 'Timeliness of Care'};
            app.Priority4ListBox.Value = 'Effectiveness of Care';
            app.Priority5ListBoxLabel = uilabel(app.UIFigure);
            app.Priority5ListBoxLabel.Text = 'Priority 5';
            app.Priority5ListBox = uilistbox(app.UIFigure);
            app.Priority5ListBox.Items = {'Effectiveness of Care', 'Efficient Use of Medical Imaging', 'Hospital Ownership', 'Hospital Type', 'Location', 'Mortality Rate', 'Item 15', 'Overall Rating', 'Quality of Emergency Rooms', 'Readmission', 'Patient Experience Rating', 'Safety of Care', 'Timeliness of Care'};
            app.Priority5ListBox.Value = 'Effectiveness of Care';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @Submit, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end